classdef RansomApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        RansomwareDetectionPanel    matlab.ui.container.Panel
        ResultEditField             matlab.ui.control.EditField
        ResultEditFieldLabel        matlab.ui.control.Label
        TestifRansomeButton         matlab.ui.control.Button
        STRFeatureEditField         matlab.ui.control.EditField
        STRFeatureEditFieldLabel    matlab.ui.control.Label
        DIRFeatureEditField         matlab.ui.control.EditField
        DIRFeatureEditFieldLabel    matlab.ui.control.Label
        FILESFeatureEditField       matlab.ui.control.EditField
        FILESFeatureEditFieldLabel  matlab.ui.control.Label
        REGFeatureEditField         matlab.ui.control.EditField
        REGFeatureEditFieldLabel    matlab.ui.control.Label
        DropFeatureEditField        matlab.ui.control.EditField
        DropFeatureEditFieldLabel   matlab.ui.control.Label
        ApiFeatureEditField         matlab.ui.control.EditField
        ApiFeatureEditFieldLabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TestifRansomeButton
        function TestifRansomeButtonPushed(app, event)
            api = str2num(app.ApiFeatureEditField.Value);
            drop = str2num(app.DropFeatureEditField.Value);
            reg = str2num(app.REGFeatureEditField.Value);
            files = str2num(app.FILESFeatureEditField.Value);
            dir = str2num(app.DIRFeatureEditField.Value);
            STR = str2num(app.STRFeatureEditField.Value);
            Data = [api, drop, reg, files, dir, STR];
%             whos Data
            fileData = load('ANN.mat');
            model = fileData.ANNtrainedClassifier;
            pr = model.predictFcn;
            result = pr(Data);
%             whos result
            if result == 1
                app.ResultEditField.Value = 'Ransomware';
            else
                app.ResultEditField.Value = 'GoodWare';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RansomwareDetectionPanel = uipanel(app.UIFigure);
            app.RansomwareDetectionPanel.Title = 'Ransomware Detection';
            app.ApiFeatureEditFieldLabel = uilabel(app.RansomwareDetectionPanel);
            app.ApiFeatureEditFieldLabel.Text = 'Api Feature';
            app.ApiFeatureEditField = uieditfield(app.RansomwareDetectionPanel, 'text');
            app.ApiFeatureEditField.InputType = 'digits';
            app.DropFeatureEditFieldLabel = uilabel(app.RansomwareDetectionPanel);
            app.DropFeatureEditFieldLabel.Text = 'Drop Feature';
            app.DropFeatureEditField = uieditfield(app.RansomwareDetectionPanel, 'text');
            app.DropFeatureEditField.InputType = 'digits';
            app.REGFeatureEditFieldLabel = uilabel(app.RansomwareDetectionPanel);
            app.REGFeatureEditFieldLabel.Text = 'REG Feature';
            app.REGFeatureEditField = uieditfield(app.RansomwareDetectionPanel, 'text');
            app.REGFeatureEditField.InputType = 'digits';
            app.FILESFeatureEditFieldLabel = uilabel(app.RansomwareDetectionPanel);
            app.FILESFeatureEditFieldLabel.Text = 'FILES Feature';
            app.FILESFeatureEditField = uieditfield(app.RansomwareDetectionPanel, 'text');
            app.FILESFeatureEditField.InputType = 'digits';
            app.DIRFeatureEditFieldLabel = uilabel(app.RansomwareDetectionPanel);
            app.DIRFeatureEditFieldLabel.Text = 'DIR Feature';
            app.DIRFeatureEditField = uieditfield(app.RansomwareDetectionPanel, 'text');
            app.DIRFeatureEditField.InputType = 'digits';
            app.STRFeatureEditFieldLabel = uilabel(app.RansomwareDetectionPanel);
            app.STRFeatureEditFieldLabel.Text = 'STR Feature';
            app.STRFeatureEditField = uieditfield(app.RansomwareDetectionPanel, 'text');
            app.STRFeatureEditField.InputType = 'digits';
            app.TestifRansomeButton = uibutton(app.RansomwareDetectionPanel, 'push');
            app.TestifRansomeButton.ButtonPushedFcn = createCallbackFcn(app, @TestifRansomeButtonPushed, true);
            app.TestifRansomeButton.Text = 'Test if Ransome';
            app.ResultEditFieldLabel = uilabel(app.RansomwareDetectionPanel);
            app.ResultEditFieldLabel.Text = 'Result';
            app.ResultEditField = uieditfield(app.RansomwareDetectionPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RansomApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end